classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Trajectory1ButtonGroup       matlab.ui.container.ButtonGroup
        LineButton                   matlab.ui.control.RadioButton
        SineButton                   matlab.ui.control.RadioButton
        paraboleButton               matlab.ui.control.RadioButton
        SimulateButton               matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        Trajectorydrone2ButtonGroup  matlab.ui.container.ButtonGroup
        LineButton_2                 matlab.ui.control.RadioButton
        SineButton_2                 matlab.ui.control.RadioButton
        paraboleButton_2             matlab.ui.control.RadioButton
        StopButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        Trajectory=@trajectory_generator;  % Trajectory
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('utils');
            addpath('trajectories');
            axis(app.UIAxes,'equal')
            view(app.UIAxes,90,0);
            app.Trajectory=@trajectory_generator;
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            
            Speed=1;
            opt=7;
            finish=0;
            
            if(app.LineButton.Value)
                Data{1}=[0 0 1;0 6 1];
            end
            
            if(app.SineButton.Value)
                XData=zeros(1,25);
                YData=0:0.25:6;
                ZData=2+sin(0:pi/6:4*pi);
                Data{1}=[XData' YData' ZData'];
            end
            if(app.paraboleButton.Value)
                XData=zeros(1,25);
                YData=0:0.25:6;
                ZData=9-(YData-3).^2;
                Data{1}=[XData' YData' ZData'];
            end
            if(app.LineButton_2.Value)
                Data{2}=[0 0 1;0 6 1];
            end
            
            if(app.SineButton_2.Value)
                XData=zeros(1,25);
                YData=0:0.25:6;
                ZData=2+sin(0:pi/6:4*pi);
                Data{2}=[XData' YData' ZData'];
            end
            if(app.paraboleButton_2.Value)
                XData=zeros(1,25);
                YData=0:0.25:6;
                ZData=9-(YData-3).^2;
                Data{2}=[XData' YData' ZData'];
            end
            
            %             myworld=vrview('support/asbQuadcopterWorld.wrl');
            %             quadcopter_body = myworld.QuadBody;
            %             quadcopter_body.translation=[57 0 88];
            
            MainPlot = app.UIAxes;
            splot1 = app.UIAxes2;
            splot2 = app.UIAxes3;
            % splot3 = app.UIAxes4;
            
            params = crazyflie();
            
            % real-time
            real_time = true;
            
            %% **************************** FIGURES *****************************
            disp('Initializing figures...')
            
            quadcolors = lines(2); %2drones
            
            %% *********************** INITIAL CONDITIONS ***********************
            t_total  = 25;             % Total simulated time
            tstep    = 0.01;          % this determines the time step at which the solution is given
            cstep    = 0.05;          % image capture time interval
            max_iter = t_total/cstep; % max iteration
            nstep    = cstep/tstep;
            time     = 0; % current time
            err = []; % runtime errors
            % Get start and stop position
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for qn=1:2


                controlhandle{qn} = @controller;
                trajhandle=app.Trajectory;
                trajhandle([],qn, Data{qn},Speed,opt,2);
                des_start{qn} = trajhandle(0, qn,Data{qn},Speed,opt,2);
                des_stop{qn}  = trajhandle(inf, qn,Data{qn},Speed,opt,2);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                stop{qn}  = des_stop{qn}.pos;
                x0{qn}    = init_state( des_start{qn}.pos, 0 );
                xtraj{qn} = zeros(max_iter*nstep, length(x0{qn}));
                ttraj{qn} = zeros(max_iter*nstep, 1);
                
                x{qn}         = x0{qn};        % state
            end
            
            %% ************************* RUN SIMULATION *************************
            disp('Simulation Running....')
            % Main loop
            
            
            for iter = 1:max_iter
                
                timeint = time:tstep:time+cstep;
                hold(splot1,'on');
                hold(splot2,'on');
                tic;
                time = time + cstep; % Update simulation time
                for qn =1:2
                    
                    % Initialize quad plot
                    if iter == 1
                        
                        QP{qn} = QuadPlot(qn, x0{qn}, 0.1, 0.04, quadcolors(qn,:), max_iter, MainPlot);
                        desired_state{qn} = trajhandle(time, qn, Data{qn},Speed,opt,2);
                        QP{qn}.UpdateQuadPlot(x{qn}, [desired_state{qn}.pos; desired_state{qn}.vel], time);
                        
                    end
                    
                    % Run simulation
                    FV = @(t,s) quadEOM(t, s, qn, controlhandle{qn}, trajhandle, params, Data{qn},Speed,opt,2);
                    % Run simulation
                    [tsave{qn}, xsave{qn}] = ode45(FV , timeint, x{qn});
                    x{qn}    = xsave{qn}(end, :)';
                    
                    % Save to traj
                    xtraj{qn}((iter-1)*nstep+1:iter*nstep,:) = xsave{qn}(1:end-1,:);
                    ttraj{qn}((iter-1)*nstep+1:iter*nstep) = tsave{qn}(1:end-1);
                    
                    % Update quad plot
                    quad_state{qn} = stateToQd(x{qn});
                    %   quadcopter_body.translation=[quad_state(2) quad_state(3) 88];
                    %   quadcopter_body.rotation=[0 0 1 x(3)];
                    
                    tt =time + cstep;
                    desired_state{qn} = trajhandle(tt, qn,Data{qn},Speed,opt,2);
                    hold(MainPlot,'on')
                    QP{qn}.UpdateQuadPlot(x{qn}, [desired_state{qn}.pos; desired_state{qn}.vel], tt);
                    hold(MainPlot,'off')


                    %     splot1
                    plot(splot1,ttraj{qn}(1:iter*nstep), xtraj{qn}(1:iter*nstep,2),'Color', quadcolors(qn,:));
                    %     splot2
                    plot(splot2,ttraj{qn}(1:iter*nstep), xtraj{qn}(1:iter*nstep,3),'Color', quadcolors(qn,:));
                    
                end
                t = toc;
                
                % Pause to make real-time
                
                
                hold(splot1,'off');
                hold(splot2,'off');
                
                
                % Check termination criteria
                % Check termination criteria
                if time >= 12
                    finish=1;
                    break
                end


            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                delete(app);
                
            catch
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Drones')
            app.Trajectory1ButtonGroup = uibuttongroup(app.UIFigure);
            app.Trajectory1ButtonGroup.Title = 'Trajectory 1';
            app.LineButton = uiradiobutton(app.Trajectory1ButtonGroup);
            app.LineButton.Text = 'Line';
            app.LineButton.Value = true;
            app.SineButton = uiradiobutton(app.Trajectory1ButtonGroup);
            app.SineButton.Text = 'Sine';
            app.paraboleButton = uiradiobutton(app.Trajectory1ButtonGroup);
            app.paraboleButton.Text = 'parabole';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Y(t)')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Z(t)')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'Z')
            app.Trajectorydrone2ButtonGroup = uibuttongroup(app.UIFigure);
            app.Trajectorydrone2ButtonGroup.Title = 'Trajectory drone 2';
            app.LineButton_2 = uiradiobutton(app.Trajectorydrone2ButtonGroup);
            app.LineButton_2.Text = 'Line';
            app.LineButton_2.Value = true;
            app.SineButton_2 = uiradiobutton(app.Trajectorydrone2ButtonGroup);
            app.SineButton_2.Text = 'Sine';
            app.paraboleButton_2 = uiradiobutton(app.Trajectorydrone2ButtonGroup);
            app.paraboleButton_2.Text = 'parabole';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end